classdef cartoonizeApp_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadImagePanel            matlab.ui.container.Panel
        PredefinedImagesLabel     matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        LoadPredefined            matlab.ui.control.Button
        LoadCustomImg             matlab.ui.control.Button
        ImagePanel1               matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        gray_lamp                 matlab.ui.control.Lamp
        RGBLabel                  matlab.ui.control.Label
        GrayscaleLabel            matlab.ui.control.Label
        r_lamp                    matlab.ui.control.Lamp
        g_lamp                    matlab.ui.control.Lamp
        b_lamp                    matlab.ui.control.Lamp
        OriginalImageSwitchLabel  matlab.ui.control.Label
        OriginalImageSwitch       matlab.ui.control.RockerSwitch
        BlurPanel                 matlab.ui.container.Panel
        SigmaKnobLabel            matlab.ui.control.Label
        SigmaKnob                 matlab.ui.control.Knob
        FilterSizeKnobLabel       matlab.ui.control.Label
        FilterSizeKnob            matlab.ui.control.DiscreteKnob
        CartoonizePanel           matlab.ui.container.Panel
        divisionsSliderLabel      matlab.ui.control.Label
        divSlider                 matlab.ui.control.Slider
        Cvt2GrayButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        I % Original Image
        currentI
        defaultSigma = 1
        defaultFilterSize = '3' % For Discrete Knob, marking should be char/string
        defaultDiv = 16
    end
    
    methods (Access = private)
        
        function detectChannel(app)
            
            if strcmp(app.OriginalImageSwitch.Value,'Off')
                ch = size(app.currentI,3);
                if(ch==3)
                    app.gray_lamp.Enable='off';
                    app.GrayscaleLabel.Enable='off';
                    
                    app.r_lamp.Enable='on';
                    app.g_lamp.Enable='on';
                    app.b_lamp.Enable='on';
                    app.RGBLabel.Enable='on';
                    
                    app.Cvt2GrayButton.Enable='on';
                    
                elseif(ch==1)
                    app.gray_lamp.Enable='on';
                    app.GrayscaleLabel.Enable='on';
                    
                    app.r_lamp.Enable='off';
                    app.g_lamp.Enable='off';
                    app.b_lamp.Enable='off';
                    app.RGBLabel.Enable='off';
                    
                    app.Cvt2GrayButton.Enable='off';
                    
                end
            else
                ch = size(app.I,3)
                if(ch==3)
                    app.gray_lamp.Enable='off';
                    app.GrayscaleLabel.Enable='off';
                    
                    app.r_lamp.Enable='on';
                    app.g_lamp.Enable='on';
                    app.b_lamp.Enable='on';
                    app.RGBLabel.Enable='on';
                    
                    app.Cvt2GrayButton.Enable='on';
                    
                elseif(ch==1)
                    app.gray_lamp.Enable='on';
                    app.GrayscaleLabel.Enable='on';
                    
                    app.r_lamp.Enable='off';
                    app.g_lamp.Enable='off';
                    app.b_lamp.Enable='off';
                    app.RGBLabel.Enable='off';
                    
                    app.Cvt2GrayButton.Enable='off';
                    
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OriginalImageSwitch.Value = 'Off';
            app.I = imread(app.DropDown.Value);
            app.currentI = app.I;
            detectChannel(app);
            imshow(app.I,'Parent',app.UIAxes);      
        end

        % Button pushed function: LoadPredefined
        function LoadPredefinedButtonPushed(app, event)
            app.I = imread(app.DropDown.Value);
            detectChannel(app);
            imshow(app.I,'Parent',app.UIAxes);
            
            app.SigmaKnob.Value = app.defaultSigma;
            app.FilterSizeKnob.Value = app.defaultFilterSize;
            app.divSlider.Value = app.defaultDiv;
            app.currentI = app.I;
            detectChannel(app);
        end

        % Callback function
        function GaussianBlurButtonPushed(app, event)

        end

        % Value changing function: SigmaKnob
        function SigmaKnobValueChanging(app, event)
            changingValue = event.Value;
            gaussianBlur_sigma = imgaussfilt(app.currentI,changingValue,'FilterSize',str2num(app.FilterSizeKnob.Value));
            imshow(gaussianBlur_sigma,'Parent',app.UIAxes);
            %app.currentI = gaussianBlur_sigma;
            detectChannel(app);
        end

        % Value changed function: FilterSizeKnob
        function FilterSizeKnobValueChanged(app, event)
            value = app.FilterSizeKnob.Value;
            gaussianBlur_fsize = imgaussfilt(app.currentI,app.SigmaKnob.Value,'FilterSize',str2num(value));
            imshow(gaussianBlur_fsize,'Parent',app.UIAxes);
            %app.currentI = gaussianBlur_fsize;
            detectChannel(app);
        end

        % Value changing function: divSlider
        function divSliderValueChanging(app, event)
            div = event.Value;
            numSteps = 256/div;
            stepdown = @(pixel) pixel-mod(pixel,numSteps);
            gaussBlurImg = imgaussfilt(app.I,app.SigmaKnob.Value,'FilterSize',str2num(app.FilterSizeKnob.Value));
            ShadedEffect=stepdown(gaussBlurImg) ;
            imshow(ShadedEffect,'Parent',app.UIAxes);
            app.currentI = ShadedEffect;
            detectChannel(app);
        end

        % Button pushed function: LoadCustomImg
        function LoadCustomPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            
            % p_name : Path Name, f_name : File Name
            [f_name, p_name] = uigetfile(filterspec);
            
            impath=strcat(p_name,f_name); %Complete image path
            app.I=imread(impath);
            imshow(app.I,'Parent',app.UIAxes);
            
            app.SigmaKnob.Value = app.defaultSigma;
            app.FilterSizeKnob.Value = app.defaultFilterSize;
            app.divSlider.Value = app.defaultDiv;
            app.currentI = app.I;
            detectChannel(app);
        end

        % Button pushed function: Cvt2GrayButton
        function Cvt2GrayButtonPushed(app, event)
            grayImg = rgb2gray(app.I);
            imshow(grayImg,'Parent',app.UIAxes);
            
            app.SigmaKnob.Value = app.defaultSigma;
            app.FilterSizeKnob.Value = app.defaultFilterSize;
            app.divSlider.Value = app.defaultDiv;
            app.currentI = grayImg;
            detectChannel(app);
        end

        % Value changed function: OriginalImageSwitch
        function OriginalImageSwitchValueChanged(app, event)
            value = app.OriginalImageSwitch.Value;
            if strcmp(value, 'On')
                imshow(app.I,'Parent',app.UIAxes);
                detectChannel(app);
            else
                imshow(app.currentI,'Parent',app.UIAxes);
                detectChannel(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadImagePanel = uipanel(app.UIFigure);
            app.PredefinedImagesLabel = uilabel(app.LoadImagePanel);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.LoadImagePanel);
            app.DropDown.Items = {'Peppers', 'Corn', 'Cameraman', 'Face'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif', 'cameraman.tif', 'face.jpg'};
            app.DropDown.Value = 'peppers.png';
            app.LoadPredefined = uibutton(app.LoadImagePanel, 'push');
            app.LoadPredefined.ButtonPushedFcn = createCallbackFcn(app, @LoadPredefinedButtonPushed, true);
            app.LoadPredefined.Text = 'Load';
            app.LoadCustomImg = uibutton(app.LoadImagePanel, 'push');
            app.LoadCustomImg.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomPushed, true);
            app.LoadCustomImg.Text = 'Load Custom Image';
            app.ImagePanel1 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.ImagePanel1);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.gray_lamp = uilamp(app.ImagePanel1);
            app.RGBLabel = uilabel(app.ImagePanel1);
            app.RGBLabel.Text = 'RGB ';
            app.GrayscaleLabel = uilabel(app.ImagePanel1);
            app.GrayscaleLabel.Text = 'Grayscale';
            app.r_lamp = uilamp(app.ImagePanel1);
            app.g_lamp = uilamp(app.ImagePanel1);
            app.b_lamp = uilamp(app.ImagePanel1);
            app.OriginalImageSwitchLabel = uilabel(app.ImagePanel1);
            app.OriginalImageSwitchLabel.Text = 'Original Image';
            app.OriginalImageSwitch = uiswitch(app.ImagePanel1, 'rocker');
            app.OriginalImageSwitch.ValueChangedFcn = createCallbackFcn(app, @OriginalImageSwitchValueChanged, true);
            app.BlurPanel = uipanel(app.UIFigure);
            app.BlurPanel.Title = 'Gaussian Blur';
            app.SigmaKnobLabel = uilabel(app.BlurPanel);
            app.SigmaKnobLabel.Text = 'Sigma';
            app.SigmaKnob = uiknob(app.BlurPanel, 'continuous');
            app.SigmaKnob.Limits = [0.1 10];
            app.SigmaKnob.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.SigmaKnob.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SigmaKnob.ValueChangingFcn = createCallbackFcn(app, @SigmaKnobValueChanging, true);
            app.SigmaKnob.MinorTicks = [];
            app.SigmaKnob.Value = 1;
            app.FilterSizeKnobLabel = uilabel(app.BlurPanel);
            app.FilterSizeKnobLabel.Text = 'FilterSize';
            app.FilterSizeKnob = uiknob(app.BlurPanel, 'discrete');
            app.FilterSizeKnob.Items = {'3', '5', '7', '9', '11', '13', '15'};
            app.FilterSizeKnob.ValueChangedFcn = createCallbackFcn(app, @FilterSizeKnobValueChanged, true);
            app.FilterSizeKnob.Value = '3';
            app.CartoonizePanel = uipanel(app.UIFigure);
            app.CartoonizePanel.Title = 'Cartoonize';
            app.divisionsSliderLabel = uilabel(app.CartoonizePanel);
            app.divisionsSliderLabel.Text = 'divisions';
            app.divSlider = uislider(app.CartoonizePanel);
            app.divSlider.Limits = [1 127];
            app.divSlider.MajorTicks = [1 16 32 48 64 80 96 112 127];
            app.divSlider.MajorTickLabels = {'1', '16', '32', '48', '64', '80', '96', '112', '127', '127'};
            app.divSlider.ValueChangingFcn = createCallbackFcn(app, @divSliderValueChanging, true);
            app.divSlider.MinorTicks = [4 8 12 20 24 28 36 40 44 52 56 60 68 72 76 84 88 92 100 104 108 116 120 124];
            app.divSlider.Value = 32;
            app.Cvt2GrayButton = uibutton(app.UIFigure, 'push');
            app.Cvt2GrayButton.ButtonPushedFcn = createCallbackFcn(app, @Cvt2GrayButtonPushed, true);
            app.Cvt2GrayButton.Text = {'Convert to Grayscale'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cartoonizeApp_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end